classdef DM_CM_Calculator_Experimental_APREOFEI_Quanta_SourceCode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        NSize_Label                   matlab.ui.control.NumericEditField
        ManResVal                     matlab.ui.control.NumericEditField
        ManResButton                  matlab.ui.control.Button
        ConnectedButton               matlab.ui.control.StateButton
        NSize                         matlab.ui.control.Slider
        NeighborhoodSizeFATMLabel     matlab.ui.control.Label
        FilterMethodButtonGroup       matlab.ui.container.ButtonGroup
        NoneButton_2                  matlab.ui.control.ToggleButton
        SizeButton                    matlab.ui.control.ToggleButton
        PercentageButton              matlab.ui.control.ToggleButton
        TopType                       matlab.ui.container.ButtonGroup
        NoTopButton                   matlab.ui.control.RadioButton
        ManualTopButton               matlab.ui.control.RadioButton
        ImagesSavedLabel              matlab.ui.control.Label
        DiscSizeLabel                 matlab.ui.control.Label
        StrelSize                     matlab.ui.control.Spinner
        DrawPerimeterSwitch           matlab.ui.control.Switch
        DrawPerimeterSwitchLabel      matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        RunTime                       matlab.ui.control.Label
        FileNameGUI                   matlab.ui.control.Button
        AnalyzeButton                 matlab.ui.control.Button
        T                             matlab.ui.control.Slider
        ThresholdFMTMLabel            matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        OverlayButton_2               matlab.ui.control.StateButton
        ClearEditsButton              matlab.ui.control.Button
        MaskPicker                    matlab.ui.control.Switch
        PainterPanel                  matlab.ui.container.Panel
        OverlayButton                 matlab.ui.control.StateButton
        FillingColorSwitch            matlab.ui.control.Switch
        FillingColorSwitchLabel       matlab.ui.control.Label
        UndoButton                    matlab.ui.control.Button
        NewEditButton                 matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        OpenPainterButton             matlab.ui.control.Button
        FilesList                     matlab.ui.control.ListBox
        ImagesinImmediateFolderLabel  matlab.ui.control.Label
        ImageCollection               matlab.ui.container.TabGroup
        OriginalSEMImageTab           matlab.ui.container.Tab
        OrigImage                     matlab.ui.control.UIAxes
        FrontFaceOnlyTab              matlab.ui.container.Tab
        DebugImages                   matlab.ui.control.UIAxes
        CurrentEditedMaskTab          matlab.ui.container.Tab
        PaintedImage                  matlab.ui.control.UIAxes
        TitleLabel                    matlab.ui.control.Label
        CurrentFileNameLabel          matlab.ui.control.Label
        SkeletonizingPanel            matlab.ui.container.Panel
        MaskPicker_3                  matlab.ui.control.StateButton
        SkelDoneButton                matlab.ui.control.Button
        SkelThick                     matlab.ui.control.Slider
        ThickeningSizeLabel           matlab.ui.control.Label
        SkelThickLabel                matlab.ui.control.NumericEditField
        ShowSkeletonButton            matlab.ui.control.Button
        CopySkelPoreDistButton        matlab.ui.control.Button
        FMTM                          matlab.ui.control.UIAxes
        FATM                          matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        OpenSkeletonButton            matlab.ui.control.Button
        MaskPicker_2                  matlab.ui.control.Switch
        CopyPoreDistButton            matlab.ui.control.Button
        ShrinkThickVal                matlab.ui.control.NumericEditField
        CopyDataButton                matlab.ui.control.Button
        ShrinkVal                     matlab.ui.control.Button
        ImageAnalyzerButton           matlab.ui.control.Button
        InstructionsButton            matlab.ui.control.Button
        SaveImagesButtonGroup         matlab.ui.container.ButtonGroup
        BothButton                    matlab.ui.control.ToggleButton
        FMTMButton                    matlab.ui.control.ToggleButton
        FATMButton                    matlab.ui.control.ToggleButton
        NoneButton                    matlab.ui.control.ToggleButton
        BinaryMaskLabel_2             matlab.ui.control.Label
        BMCount                       matlab.ui.control.Button
        EdgeDetectLabel_2             matlab.ui.control.Label
        EDCount                       matlab.ui.control.Button
        EdgeDetectLabel               matlab.ui.control.Label
        BinaryMaskLabel               matlab.ui.control.Label
        EDVal                         matlab.ui.control.Button
        BMVal                         matlab.ui.control.Button
        ShrinkButton                  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = private)
        g_fileName % File Path of current image
        g_cFolderName % Folder Path of current image
        g_bwROI % The front face only mask, b&w
        g_imT % The front face only crop, grayscale
        g_peri % length of perimeter
        g_editedMask % 3d array of all the edits
        g_pSize_UM2 = -1 % Area of pixel
        g_pLength_UM = -1 % Length of pixel
        g_manDist % Length of manual resolution line
        g_AutoPoreDist % pore sizes for FATM
        g_ManPoreDist % pore sizes for FMTM
        g_imgFATM % the image displayed in the FATM box
        g_imgFMTM % the image displayed in the FMTM box
        g_imgSkel % g_imgSkel{1} is skeleton of FATM, g_imgSkel{2} is skeleton of FMTM
    end
    methods (Access = private)
        function fFillFileList(app)
            lFileName = app.g_fileName;
            [cFolderName, ~, ext] = fileparts(lFileName);
            dFolderName = cFolderName.append(strcat("\*", ext));
            cDir = dir(dFolderName);
            tDir = struct2cell(cDir)';
            fileList = tDir(:,1);
            s = size(fileList);
            app.FilesList.Items = fileList;
            app.FilesList.ItemsData = 1:s(1);
            app.g_cFolderName = cFolderName;
        end
        function [imH, DM_Manual] = fManualProcessing(app, imW)
            bwROI = app.g_bwROI;
            Thres = app.T.Value/255;
            imBW = imbinarize(imW, Thres);
            se = strel('disk', app.StrelSize.Value);
            imH = imclose(imBW, se);
            if strcmp(char(app.DrawPerimeterSwitch.Value), 'On') && strcmp(string(app.TopType.SelectedObject.Text), "Manual Top")
                periL = bwperim(bwROI);
                B = cell2mat(bwboundaries(bwROI));

                imH = insertShape(imH*1, 'Polygon', [B(:,2), B(:,1)], 'LineWidth', 1, 'Color', 'white');
                properties = sortrows(regionprops('table', periL, "Perimeter"), 'Perimeter', 'descend');
                peri = properties.Perimeter(1,1);
            else
                imH = imclose(imBW, se);
                peri = 0;
            end
            imH = fApplyFilters(app, imH);
            if app.ConnectedButton.Value == 1
                imH = bwareafilt(imH, 1, 'largest');
            end
            DM_Manual = 1-fSLnoWhite(app, logical(imH(:,:,1)), peri);
            app.g_peri = peri;
        end
        function [BW, DM_auto] = fAutoProcessing(app, imW)
            bwROI = app.g_bwROI*1;
            %             Thres = adaptthresh(imW, 'Statistic',"gaussian", "ForegroundPolarity","bright", 'NeighborhoodSize', 301);
            Thres = adaptthresh(imW, ...
                'Statistic',"gaussian", ...
                'ForegroundPolarity',"bright",...
                "NeighborhoodSize", 2*floor(app.NSize.Value/2)+1);
            % neighborhoodsize between 751 and 999 worked pretty good, keep
            % neighborhoodsize of 61 worked reliably enough.
            imBW = imbinarize(imW, Thres);
            se = strel('disk', abs(app.StrelSize.Value + 1));
            BW = imclose(imBW, se);
            if strcmp(char(app.DrawPerimeterSwitch.Value), 'On') && strcmp(string(app.TopType.SelectedObject.Text), "Manual Top")
                periL = bwperim(bwROI);
                B = cell2mat(bwboundaries(bwROI));
                BW = insertShape(BW*1, 'Polygon', [B(:,2), B(:,1)], 'LineWidth', 1, 'Color', 'white');
                properties = sortrows(regionprops('table', periL, "Perimeter"), 'Perimeter', 'descend');
                peri = properties.Perimeter(1,1);
            else
                BW = imclose(imBW, se);
                peri = 0;
            end
            BW = fApplyFilters(app, BW);
            if app.ConnectedButton.Value == 1
                BW = bwareafilt(BW, 1, 'largest');
            end
            DM_auto = 1-fSLnoWhite(app, logical(BW(:,:,1)), peri);
            app.g_peri = peri;
        end
        function [imH, DM_Paint] = fPaintProcessing(app, imW)
            Thres = app.T.Value/255;
            imBW = imbinarize(imW, Thres);
            se = strel('disk', 0);
            imH = imclose(imBW, se);
            imH = logical(imH(:,:,1));
            DM_Paint = 1-fSLnoWhite(app, logical(imH(:,:,1)), 0);
        end
        function filt = fApplyFilters(app, img)
            if strcmp(app.FilterMethodButtonGroup.SelectedObject.Text, "Percentage")
                img = ~logical(img(:,:,1));
                properties = sortrows(regionprops('table', img, "Area"), ...
                    'Area', 'descend');
                LB = floor(mean(properties.Area)+0.5*std(properties.Area));
                filt = ~bwareaopen(img, LB);
            elseif strcmp(app.FilterMethodButtonGroup.SelectedObject.Text, "Size")
                img = ~logical(img(:,:,1));
                properties = sortrows(regionprops('table', img, "Area"), ...
                    'Area', 'descend');
                filt = ~bwareafilt(img, min(floor(0.05*length(properties.Area))+1, 60), 'largest');
            else
                filt = logical(img(:,:,1));
            end
        end
        function fUpdateBottomFileName(app)
            d = split(app.g_fileName, "\");
            base = "Current File: ";
            app.CurrentFileNameLabel.Visible = 1;
            app.CurrentFileNameLabel.Text = strcat(base, d(end,end));
        end
        function M = fAddSideBar(~, orig)
            M = orig(:,:,1);
            s= size(M);
            M(:, 1:1) = 255;
            M(:, (s(1,2)-1):s(1,2)) = 255;
        end
        function img = fSEMDatabarCut(~, orig)
            s = size(orig);
            sz = 0.90*s(1,1);
            img = imcrop(orig,[1 1 s(1,2) sz]);
        end
        function SL = fSLnoWhite(~, orig, peri)
            %obtain properties of image
            BW_out = bwareafilt(orig, 2);
            s = size(orig);
            properties = regionprops(BW_out, {'Area', 'FilledArea', 'Solidity'});
            properties = struct2table(properties);
            properties = sortrows(properties, 'Area', 'descend'); %sort the white areas by Area from largest to smallest
            if peri == 0
                mHole = properties.FilledArea(1,1)-sum(properties.Area(:,:))-5*s(1,1)-peri;
                mArea = properties.FilledArea(1,1)- 5*s(1,1)-peri;
            elseif peri == 1
                mHole = properties.FilledArea(1,1)-properties.Area(1,1)-5*s(1,1);
                mArea = properties.FilledArea(1,1)- 5*s(1,1);
            else
                mHole = properties.FilledArea(1,1)-sum(properties.Area(:,:))-peri;
                mArea = properties.FilledArea(1,1)-peri;
            end
            %Calculate Dm. Dm = macrovoid area/total surface area
            SL = 1- mHole/mArea;
            if SL > 1
                SL = 1;
            end
        end
        function [imT, bwROI] = fCutManual(~, imW)
            figure('Name', "Manual Cropping");
            imshow(im2uint16(imW));
            ax = gca;
            ROI = drawpolygon(ax, 'Color', [0.2235 1 0.0784]);
            bwROI = createMask(ROI);
            if(strcmp(char(class(imW)), 'uint8'))
                imT = uint8(imW).*uint8(bwROI);
            elseif(strcmp(char(class(imW)), 'uint16'))
                imT = uint16(imW).*uint16(bwROI);
            end
            pause(0.01);
            close all;
        end
        function [newMask, pROI] = fPaintManual(app)
            editedMask = app.g_editedMask;
            s = size(editedMask);
            st = size(s);
            if st(2) < 3
                previousMask = editedMask(:,:,1);
            else
                previousMask = editedMask(:,:,end);
            end
            selectedColor = string(app.FillingColorSwitch.Value);
            fName = strcat("Painter--Brush Color: ", selectedColor);
            figure('Name',fName);
            if app.OverlayButton.Value == 1
                seePMask = imdilate(bwperim(previousMask), strel('disk', 3));
                imshow(im2uint16(imoverlay(app.g_imT, seePMask)));
            else
                imshow(im2uint16(previousMask));
            end
            ax = gca;
            if selectedColor == "Black"
                ROI = drawpolygon(ax, 'Color', [0 0 0], 'StripeColor', [0.2471 1 1]);
            else
                ROI = drawpolygon(ax, 'Color', [0.6 0.6667 0.7098], 'StripeColor', [0.2471 1 1]);
            end
            try
                pROI = createMask(ROI);
                pROI = cast(pROI, char(class(previousMask)));
                if strcmp(selectedColor, "White")
                    newMask = previousMask + pROI;
                else %if black
                    newMask = previousMask - pROI;
                end
                newMask(newMask > 0) = 1;
                editedMask = cat(3, editedMask, newMask);
                if app.OverlayButton.Value == 1
                    seeNMask = imdilate(bwperim(newMask), strel('disk', 3));
                    imagesc(app.PaintedImage, im2uint16(imoverlay(app.g_imT, seeNMask)));
                else
                    imagesc(app.PaintedImage, im2uint16(newMask));
                end
                close all;
                app.g_editedMask = editedMask;
            catch
                close all;
            end
        end
        function count = fCountMacrovoids(~, imW)
            BW_out = bwareafilt(imcomplement(imW), 10000);
            properties = regionprops(BW_out, {'Area'});
            s = size(properties);
            count = max(s(1,1)-2, 0);
        end
        function fGetPixelLength(app)
            %to go from pixels to um, multiply pixels by pLength or pSize
            %pLength_UM: length of pixel
            %pSize_UM2: area of pixel
            [~, ~, ext] = fileparts(app.g_fileName);
            if strcmp(ext, ".tif") || strcmp(ext, ".tiff")
                app.SaveImagesButtonGroup.Enable = 'on';
                metadata = imfinfo(app.g_fileName);
                try
                    usefulMetadata = metadata.UnknownTags.Value;
                    if contains(usefulMetadata, "Crossbeam")
                        % for the Zeiss SEMs
                        pRatio = extractBetween(usefulMetadata, "Image Pixel Size = ", "nm");
                        pRatio = string(pRatio);
                        pRatio = str2double(pRatio);
                        app.g_pLength_UM = pRatio/1000;
                    else
                        % for the Apreo and FEI SEMs
                        pRatio = extractBetween(metadata.UnknownTags.Value, "PixelWidth=", "P");
                        pRatio = string(pRatio);
                        pRatio = double(pRatio(2,1));
                        app.g_pLength_UM = pRatio*1E6;
                    end
                    app.g_pSize_UM2 = app.g_pLength_UM^2;
                catch
                    wMSG = "Metadata isn't formatted as expected or missing," + ...
                        " create the manual top then use" + ...
                        " the Manual Resolution button to add in the resolution";
                    warning(wMSG);
                    app.SaveImagesButtonGroup.Enable = 'off';
                    app.g_pLength_UM = -1;
                    app.g_pSize_UM2 = -1;
                end
            else
                app.SaveImagesButtonGroup.Enable = 'off';
                app.g_pLength_UM = -1;
                app.g_pSize_UM2 = -1;
            end
        end
        function [cArea, cArea_UM2] = fGetCrossSectionalArea(app, BW)
            %cArea is in pixels, cArea_UM2 is in um^2
            pSize_UM2 = app.g_pSize_UM2;
            BW_out = bwareafilt(BW, 10000);
            properties = regionprops(BW_out, {'FilledArea'});
            properties = struct2table(properties);
            properties = sortrows(properties, 'FilledArea', 'descend'); %sort the white areas by Area from largest to smallest
            cArea = double(table2array(properties(1,1)));
            cArea_UM2 = cArea*pSize_UM2;
        end
        function fSaveImg(app, imH, BW)
            fileName = app.g_fileName; %imH is FMTM, BW is FATM
            fileName_FATM = insertBefore(fileName, ".ti", "_FATM");
            fileName_FMTM = insertBefore(fileName, ".ti", "_FMTM");
            fileName_FATM = replace(fileName_FATM, "tiff", "png");
            fileName_FMTM = replace(fileName_FMTM, "tiff", "png");
            fileName_FATM = replace(fileName_FATM, "tif", "png");
            fileName_FMTM = replace(fileName_FMTM, "tif", "png");

            softTXT = "DM & CM Calculator";
            descimH = "From manual thresholding";
            descBW= "From automated thresholding";
            if app.g_pLength_UM == -1
                xRes = 1;
            else
                xRes = app.g_pLength_UM*1E-6;
            end

            if app.NoneButton.Value == 0
                app.ImagesSavedLabel.Visible = 1;
            else
                app.ImagesSavedLabel.Text = "";
            end
            if app.BothButton.Value == 1
                imwrite(imH, fileName_FMTM, ...
                    "ResolutionUnit","meter", ...
                    "XResolution", xRes, ...
                    "Software", softTXT, "Description", descimH);
                imwrite(BW, fileName_FATM, "png", ...
                    "ResolutionUnit","meter", ...
                    "XResolution", xRes, ...
                    "Software", softTXT, "Description", descBW);
                app.ImagesSavedLabel.Text = "FATM and FMTM Saved";
            end
            if app.FMTMButton.Value == 1
                imwrite(imH, fileName_FMTM, "png", ...
                    "ResolutionUnit","meter", ...
                    "XResolution", xRes, ...
                    "Software", softTXT, "Description", descimH);
                app.ImagesSavedLabel.Text = "FMTM Saved";
            end
            if app.FATMButton.Value == 1
                imwrite(BW, fileName_FATM, "png", ...
                    "ResolutionUnit","meter", ...
                    "XResolution", xRes, ...
                    "Software", softTXT, "Description", descBW);
                app.ImagesSavedLabel.Text = "FATM Saved";
            end
        end
        function A = fDeleteDupl(~, A)
            U = unique(A); % finds unique elements in A, contains duplicates
            for n = 1:length(U)
                idxs = find(A==U(n)); %finds indexs of each element in U
                if length(idxs) > 1 % if indexes of element in U > 1, delete them all
                    A(idxs) = [];
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            close all;
            colormap(app.OrigImage, "gray");
            colormap(app.DebugImages, "gray");
            colormap(app.FATM, "gray");
            colormap(app.FMTM, "gray");
            colormap(app.PaintedImage, "gray");
            warning('off', 'images:bwfilt:tie');
            app.UIFigure.Resize = 'on';
            app.UIFigure.AutoResizeChildren = 'off';
            app.UIFigure.Scrollable = 'on';
            movegui(app.UIFigure, "center");
            app.CurrentFileNameLabel.Visible = 0;
            app.NSize_Label.Value = 2*floor(app.NSize.Value/2)+1;
            clear figure;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = string(event.Key);
            switch key
                case 'o'
                    % same as pressing "Load Image"
                    FileNameGUIPushed(app,matlab.ui.eventdata.ButtonPushedData);
                case 'return'
                    % same as pressing "Analyze"
                    AnalyzeButtonPushed(app,matlab.ui.eventdata.ButtonPushedData);
                case 'p'
                    % Same as pressing "Open Painter"
                    OpenPainterButtonPushed(app, matlab.ui.eventdata.ButtonPushedData);
                case 'r'
                    % force enable everything
                    app.LeftPanel.Enable = 'on';
                    app.RightPanel.Enable = 'on';
                    app.FilesList.Enable = 'on';
                    app.ClearEditsButton.Enable = 'on';
                    app.OpenPainterButton.Enable = 'on';
                    app.MaskPicker.Enable = 'on';
                    app.Lamp.Color = [0.5 0.5 0.5] ; %turn lamp off
                case 'c'
                    % Same as pressing Copy Data button
                    CopyDataButtonPushed(app, matlab.ui.eventdata.ButtonPushedData);
                case 'a'
                    % display the area of the binary mask
                    disp(sum(sum(app.g_bwROI))*app.g_pSize_UM2);
                case 's'
                    % displays the skeletonized binary mask for both FATM
                    % And FMTM
                    img{1} = bwskel(app.g_imgFATM);
                    img{2} = bwskel(app.g_imgFMTM);
                    fig = figure();
                    titles = ["FATM", "FMTM"];
                    tlo = tiledlayout(fig, 1,2, 'TileSpacing', 'compact');
                    for i = 1:numel(img)
                        ax = nexttile(tlo);
                        imshow(img{i}, 'Parent', ax);
                        title([titles(i), ' skeletonized']);
                    end
            end

        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            close all;
            app.LeftPanel.Enable = 'off';
            app.RightPanel.Enable = 'off';
            app.FilesList.Enable = 'off';
            app.ClearEditsButton.Enable = 'off';
            app.OpenPainterButton.Enable = 'off';
            app.MaskPicker.Enable = 'off';
            tic;
            app.Lamp.Color = [1 0 0]; %turn lamp on
            pause(0.001);
            fileName = app.g_fileName;
            imT = app.g_imT;
            orig = imread(fileName);
            imD = fSEMDatabarCut(app, im2uint8(orig));
            if strcmp(string(app.TopType.SelectedObject.Text), "No Top")
                imT = imD;
            end
            s = size(app.g_editedMask);
            st = size(s);
            if st(2) >= 3
                if strcmp(string(app.MaskPicker.Value), "FMTM")
                    imWm = app.g_editedMask(:,:,end);
                    imWa = fAddSideBar(app, imT);
                    %Auto Processing
                    [BW, DM1] = fAutoProcessing(app, imWa);
                    [~, A_cArea_UM2] = fGetCrossSectionalArea(app, BW);
                    %Manual Mask
                    [imH, DM2] = fPaintProcessing(app, imWm);
                    [~, M_cArea_UM2] = fGetCrossSectionalArea(app, imH);
                else
                    imWa = app.g_editedMask(:,:,end);
                    imWm = fAddSideBar(app, imT);
                    %Auto Processing
                    [BW, DM1] = fPaintProcessing(app, imWa);
                    [~, A_cArea_UM2] = fGetCrossSectionalArea(app, BW);
                    %Manual Mask
                    [imH, DM2] = fManualProcessing(app, imWm);
                    [~, M_cArea_UM2] = fGetCrossSectionalArea(app, imH);
                end
            else
                imW = fAddSideBar(app, imT);
                %Auto Processing
                [BW, DM1] = fAutoProcessing(app, imW);
                [~, A_cArea_UM2] = fGetCrossSectionalArea(app, BW);
                %Manual Mask
                [imH, DM2] = fManualProcessing(app, imW);
                [~, M_cArea_UM2] = fGetCrossSectionalArea(app, imH);
            end
            %Display Images
            imagesc(app.DebugImages, fAddSideBar(app, imT));
            imagesc(app.FMTM, imH);
            imagesc(app.FATM, BW);

            app.g_imgFMTM = imH;
            app.g_imgFATM = BW;
            %Display Results
            app.EDVal.Text = num2str(DM1);
            app.BMVal.Text = num2str(DM2(1,1));
            if app.g_pLength_UM == -1
                app.EDCount.Text = "no resolution data";
                app.BMCount.Text = "no resolution data";
            else
                app.EDCount.Text = num2str((fCountMacrovoids(app, BW)./A_cArea_UM2));
                app.BMCount.Text = num2str((fCountMacrovoids(app, imH)./M_cArea_UM2));
            end
            %Save Image
            fSaveImg(app, imH, BW);

            %Get pore distributions
            if strcmp(string(app.TopType.SelectedObject.Text), "No Top")
                app.g_bwROI = 1;
            end
            imgFATM = (~BW).*app.g_bwROI;
            imgFMTM = (~imH).*app.g_bwROI;
            imgFATM = logical(imgFATM);
            imgFMTM = logical(imgFMTM);
            app.g_ManPoreDist = sortrows(regionprops('table', ...
                imgFMTM, "Area"), 'Area', 'descend');
            app.g_AutoPoreDist = sortrows(regionprops('table', ...
                imgFATM, "Area"), 'Area', 'descend');

            app.Lamp.Color = [0.5 0.5 0.5] ; %turn lamp off
            app.RunTime.Text = sprintf('%6.3f sec',toc);
            app.LeftPanel.Enable = 'on';
            app.RightPanel.Enable = 'on';
            app.FilesList.Enable = 'on';
            app.ClearEditsButton.Enable = 'on';
            app.OpenPainterButton.Enable = 'on';
            app.MaskPicker.Enable = 'on';
            app.OpenSkeletonButton.Visible = 1;
            CopyDataButtonPushed(app, matlab.ui.eventdata.ButtonPushedData);
            global global_FMTM;
            global global_FATM;
            global global_cropGray;
            global_cropGray = app.g_imT;
            global_FMTM = app.g_imgFMTM;
            global_FATM = app.g_imgFATM;
        end

        % Value changed function: T
        function TValueChanged(app, event)
            app.Lamp.Color = [1 1 0];
            app.Label.Text = num2str(app.T.Value);
            fileName = app.g_fileName;
            orig = imread(fileName);
            imD = fSEMDatabarCut(app, im2uint8(orig));
            imW = fAddSideBar(app, imD);
            if strcmp(string(app.TopType.SelectedObject.Text), "Manual Top")
                imTi = app.g_imT;
            else
                imTi = imW;
            end
            [imH, ~] = fManualProcessing(app, imTi);
            imagesc(app.FMTM, imH);
            app.Lamp.Color = [0.5 0.5 0.5];
        end

        % Value changed function: StrelSize
        function StrelSizeValueChanged(app, event)
            app.Lamp.Color = [1 1 0];
            fileName = app.g_fileName;
            orig = imread(fileName);
            imD = fSEMDatabarCut(app, im2uint8(orig));
            imW = fAddSideBar(app, imD);
            if strcmp(string(app.TopType.SelectedObject.Text), "Manual Top")
                imTi = app.g_imT;
            else
                imTi = imW;
            end
            [imH, ~] = fManualProcessing(app, imTi);
            imagesc(app.FMTM, imH);
            app.Lamp.Color = [0.5 0.5 0.5];
        end

        % Selection changed function: TopType
        function TopTypeSelectionChanged(app, event)
            selectedButton = app.TopType.SelectedObject;
            fileName = app.g_fileName;
            if strcmp(string(selectedButton.Text), "No Top")
                % if No Top, do this stuff
                app.DrawPerimeterSwitch.Visible = 0;
                app.DrawPerimeterSwitchLabel.Visible = 0;
                close all;
                orig = imread(fileName);
                imD = fSEMDatabarCut(app, im2uint8(orig));
                imW = fAddSideBar(app, imD);
                imT = imW;
                imBW = imbinarize(imT, app.T.Value);
                se = strel('disk', app.StrelSize.Value);
                imH = imclose(imBW, se);
                imagesc(app.FMTM, imH);
                imagesc(app.DebugImages, imT);
                app.ImageCollection.SelectedTab = app.FrontFaceOnlyTab;
            else
                % if Manual Top, do this stuff
                app.DrawPerimeterSwitch.Visible = 1;
                app.DrawPerimeterSwitchLabel.Visible = 1;
                orig = imread(fileName);
                [imT, bwROI] = fCutManual(app, orig);
                imagesc(app.DebugImages, imT);
                app.ImageCollection.SelectedTab = app.FrontFaceOnlyTab;
                app.g_bwROI = bwROI;
                if app.g_pLength_UM ~= -1
                    [out, ~] = bwferet(app.g_bwROI, "MinFeretProperties");
                    dUM = out.MinDiameter*app.g_pLength_UM;
                    thic = app.ShrinkThickVal.Value;
                    app.ShrinkVal.Text = num2str((thic-dUM)/thic);
                else
                    app.ShrinkVal.Text = "no resolution data";
                end
                app.ManResButton.Visible = 'on';
            end
            app.g_imT = imT;
        end

        % Button pushed function: FileNameGUI
        function FileNameGUIPushed(app, event)
            app.Label.Text = num2str(app.T.Value);
            app.Lamp.Color = [0.5 0.5 0.5] ;
            % Display uigetfile dialog
            filterspec = {'*.tif;*.tiff;*.png; *.jpg','Image Files'};
            [f, p] = uigetfile(filterspec);
            colormap(app.OrigImage, "gray");
            colormap(app.DebugImages, "gray");
            colormap(app.FATM, "gray");
            colormap(app.FMTM, "gray");
            colormap(app.PaintedImage, "gray");
            app.ImageCollection.SelectedTab = app.OriginalSEMImageTab;
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fileName = string([p f]);
                app.g_fileName = fileName;
                imagesc(app.OrigImage,imread(fileName));
                colormap(app.OrigImage, "gray");
                fUpdateBottomFileName(app);
                imD = fSEMDatabarCut(app, im2uint8(imread(fileName)));
                imagesc(app.DebugImages, imD);
                app.DrawPerimeterSwitch.Value = "On";
                app.DrawPerimeterSwitch.Visible = 'off';
                app.DrawPerimeterSwitchLabel.Visible = 'off';
                app.ManResButton.Visible = 'off';
                app.ManResVal.Visible = 'off';
                app.OpenSkeletonButton.Visible = 'off';
                app.ManResVal.Value = 5;
                app.RunTime.Text = "";
                app.EDVal.Text = "___";
                app.BMVal.Text = "___";
                app.EDCount.Text = "___";
                app.BMCount.Text = "___";
                app.ShrinkVal.Text = "___";
                fGetPixelLength(app);
                app.FilesList.Items = {};
                fFillFileList(app);
                clear imT;
                app.ImageCollection.SelectedTab = app.FrontFaceOnlyTab;
                app.TopType.SelectedObject = app.TopType.Buttons(2);
                app.g_editedMask = [];
            end

        end

        % Callback function
        function LoadOptionsValueChanged(app, event)
            if strcmp(char(app.LoadOptions.Value), 'Text')
                app.FileNameGUI.Visible = 0;
                app.NewFileNameEditField.Visible = 1;
                app.NewFileNameEditFieldLabel.Visible = 1;
                app.NewFileNameEditField.Editable = 1;
            elseif strcmp(char(app.LoadOptions.Value), 'GUI')
                app.FileNameGUI.Visible = 1;
                app.NewFileNameEditField.Visible = 0;
                app.NewFileNameEditFieldLabel.Visible = 0;
                app.NewFileNameEditField.Editable = 0;
            end
        end

        % Callback function
        function NewFileNameEditFieldValueChanged(app, event)
            tFileName = char(app.NewFileNameEditField.Value);
            if isfile(tFileName) == 0
            else
                fileName = [p f];
                app.g_fileName = fileName;
                imagesc(app.OrigImage,imread(fileName));
                colormap(app.OrigImage, "gray");
                fUpdateBottomFileName(app);
                imD = fSEMDatabarCut(app, im2uint8(imread(fileName)));
                imagesc(app.DebugImages, imD);
                app.NewFileNameEditField.Value = fileName;
                app.DrawPerimeterSwitch.Value = "On";
                app.DrawPerimeterSwitch.Visible = 0;
                app.DrawPerimeterSwitchLabel.Visible = 0;
                app.RunTime.Text = "";
                fGetPixelLength(app);
                app.FilesList.Items = {};
                fFillFileList(app);
                clear imT;
            end
        end

        % Value changed function: FilesList
        function FilesListValueChanged(app, event)
            app.LeftPanel.Enable = 'off';
            app.RightPanel.Enable = 'off';
            app.FilesList.Enable = 'off';
            app.ClearEditsButton.Enable = 'off';
            app.OpenPainterButton.Enable = 'off';
            app.MaskPicker.Enable = 'off';
            app.ManResButton.Visible = 'off';
            app.ManResVal.Visible = 'off';
            app.OpenSkeletonButton.Visible = 0;
            cFolderName = app.g_cFolderName;
            value = app.FilesList.Value;
            fileList = app.FilesList.Items;
            fileName = strcat(cFolderName, "\", string(fileList(value)));
            app.g_fileName = fileName;
            imagesc(app.OrigImage,imread(fileName));
            colormap(app.OrigImage, "gray");
            fUpdateBottomFileName(app);
            imD = fSEMDatabarCut(app, im2uint8(imread(fileName)));
            imagesc(app.DebugImages, imD);
            app.DrawPerimeterSwitch.Value = "On";
            app.DrawPerimeterSwitch.Visible = 0;
            app.DrawPerimeterSwitchLabel.Visible = 0;
            app.ManResVal.Value = 5;
            app.RunTime.Text = "";
            app.EDVal.Text = "___";
            app.BMVal.Text = "___";
            app.EDCount.Text = "___";
            app.BMCount.Text = "___";
            app.ShrinkVal.Text = "___";
            fGetPixelLength(app);
            app.TopType.SelectedObject = app.TopType.Buttons(2);
            app.DrawPerimeterSwitch.Visible = 0;
            app.DrawPerimeterSwitchLabel.Visible = 0;
            close all;
            orig = imread(fileName);
            imD = fSEMDatabarCut(app, im2uint8(orig));
            imW = fAddSideBar(app, imD);
            imT = imW;
            imBW = imbinarize(imT, app.T.Value);
            se = strel('disk', app.StrelSize.Value);
            imH = imclose(imBW, se);
            imagesc(app.FMTM, imH);
            imagesc(app.DebugImages, imT);
            app.ImageCollection.SelectedTab = app.FrontFaceOnlyTab;

            app.g_imT = imT;
            editedMask = [];
            orig = imread(app.g_fileName);
            imD = fSEMDatabarCut(app, im2uint8(orig));
            imW = fAddSideBar(app, imD);
            if  strcmp(string(app.TopType.SelectedObject.Text), "No Top")
                imT = imW;
            end
            if strcmp(string(app.MaskPicker.Value), "FMTM")
                %Manual Mask
                [iManualMask, ~] = fManualProcessing(app, imT);
                editedMask(:,:,1) = iManualMask;
            else
                %Auto Mask
                [iAutoMask, ~] = fAutoProcessing(app, imW);
                editedMask(:,:,1) = iAutoMask;
            end
            app.g_editedMask = editedMask;
            app.LeftPanel.Enable = 'on';
            app.RightPanel.Enable = 'on';
            app.FilesList.Enable = 'on';
            app.ClearEditsButton.Enable = 'on';
            app.OpenPainterButton.Enable = 'on';
            app.MaskPicker.Enable = 'on';
        end

        % Button pushed function: OpenPainterButton
        function OpenPainterButtonPushed(app, event)
            app.ImageCollection.SelectedTab = app.CurrentEditedMaskTab;
            imT = app.g_imT;
            editedMask = [];
            if  strcmp(string(app.TopType.SelectedObject.Text), "No Top")
                orig = imread(app.g_fileName);
                imD = fSEMDatabarCut(app, im2uint8(orig));
                imW = fAddSideBar(app, imD);
                imT = imW;
            end
            if strcmp(string(app.MaskPicker.Value), "FMTM")
                %Manual Mask
                [iManualMask, ~] = fManualProcessing(app, imT);
                editedMask(:,:,1) = iManualMask;
            else
                %Auto Mask
                [iAutoMask, ~] = fAutoProcessing(app, imT);
                editedMask(:,:,1) = iAutoMask;
            end
            if app.OverlayButton.Value == 1
                seeMask = imdilate(bwperim(editedMask(:,:,1)), strel('disk', 3));
                imagesc(app.PaintedImage, im2uint16(imoverlay(app.g_imT, seeMask)));
            else
                imagesc(app.PaintedImage, editedMask(:,:,1));
            end
            app.OpenPainterButton.Visible = 0;
            app.OpenSkeletonButton.Visible = 0;
            app.LeftPanel.Enable = 'off';
            app.RightPanel.Enable = 'off';
            app.FilesList.Enable = 'off';
            app.MaskPicker.Enable = 'off';
            app.ClearEditsButton.Enable = 'off';
            app.ClearEditsButton.Visible = 0;
            app.PainterPanel.Enable = 'on';
            app.PainterPanel.Visible = 1;
            app.g_editedMask = editedMask;
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            editedMask = app.g_editedMask;
            s = size(editedMask);
            st = size(s);
            if st(2) < 3
                imagesc(app.PaintedImage, im2uint16(editedMask(:,:,1)));
            elseif s(3) == 2
                imT = app.g_imT;
                editedMask = [];
                orig = imread(app.g_fileName);

                imD = fSEMDatabarCut(app, im2uint8(orig));
                imW = fAddSideBar(app, imD);

                if  strcmp(string(app.TopType.SelectedObject.Text), "No Top")
                    imT = imW;
                end
                if strcmp(string(app.MaskPicker.Value), "FMTM")
                    %Manual Mask
                    [iManualMask, ~] = fManualProcessing(app, imT);
                    editedMask(:,:,1) = iManualMask;
                else
                    %Auto Mask
                    [iAutoMask, ~] = fAutoProcessing(app, imW);
                    editedMask(:,:,1) = iAutoMask;
                end
                if app.OverlayButton.Value == 1
                    seeMask = imdilate(bwperim(editedMask(:,:,1)), strel('disk', 3));
                    imagesc(app.PaintedImage, im2uint16(imoverlay(app.g_imT, seeMask)));
                else
                    imagesc(app.PaintedImage, im2uint16(editedMask(:,:,1)));
                end
            elseif st(2) >= 3
                editedMask(:,:,s(3)) = [];
                if app.OverlayButton.Value == 1
                    seeMask = imdilate(bwperim(editedMask(:,:,end)), strel('disk', 3));
                    imagesc(app.PaintedImage, im2uint16(imoverlay(app.g_imT, seeMask)));
                else
                    imagesc(app.PaintedImage, im2uint16(editedMask(:,:,end)));
                end
            end
            %updates the PaintedImage display to the last edit
            app.g_editedMask = editedMask;
        end

        % Button pushed function: NewEditButton
        function NewEditButtonPushed(app, event)
            close all;
            fPaintManual(app);
        end

        % Button pushed function: ClearEditsButton
        function ClearEditsButtonPushed(app, event)
            imT = app.g_imT;
            editedMask = [];
            orig = imread(app.g_fileName);

            imD = fSEMDatabarCut(app, im2uint8(orig));
            imW = fAddSideBar(app, imD);

            if  strcmp(string(app.TopType.SelectedObject.Text), "No Top")
                imT = imW;
            end
            if strcmp(string(app.MaskPicker.Value), "FMTM")
                %Manual Mask
                [iManualMask, ~] = fManualProcessing(app, imT);
                editedMask(:,:,1) = iManualMask;
            else
                %Auto Mask
                [iAutoMask, ~] = fAutoProcessing(app, imW);
                editedMask(:,:,1) = iAutoMask;
            end
            app.g_editedMask = editedMask;
            imagesc(app.PaintedImage, editedMask);
            AnalyzeButtonPushed(app,matlab.ui.eventdata.ButtonPushedData);
        end

        % Value changed function: OverlayButton
        function OverlayButtonValueChanged(app, event)
            editedMask = app.g_editedMask;
            s = size(editedMask);
            st = size(s);
            if st(2) < 3
                previousMask = editedMask(:,:,1);
            else
                previousMask = editedMask(:,:,end);
            end
            if app.OverlayButton.Value == 1
                seePMask = imdilate(bwperim(previousMask), strel('disk', 3));
                colormap(app.PaintedImage, "gray");
                imagesc(app.PaintedImage, im2uint16(imoverlay(app.g_imT, seePMask)));
            else
                colormap(app.PaintedImage, "gray");
                imagesc(app.PaintedImage, im2uint16(previousMask));
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.ImageCollection.SelectedTab = app.OriginalSEMImageTab;
            app.OpenPainterButton.Visible = 1;
            app.OpenSkeletonButton.Visible = 1;
            app.LeftPanel.Enable = 'on';
            app.RightPanel.Enable = 'on';
            app.MaskPicker.Enable = 'on';
            app.PainterPanel.Enable = 'off';
            app.FilesList.Enable = 'on';
            app.PainterPanel.Visible = 0;
            app.ClearEditsButton.Visible = 1;
            app.ClearEditsButton.Enable = 'on';
            AnalyzeButtonPushed(app,matlab.ui.eventdata.ButtonPushedData);
        end

        % Button pushed function: InstructionsButton
        function InstructionsButtonPushed(app, event)
            instructions = app.UIFigure;
            uialert(instructions, sprintf(['1. Click Load Image to load image \n' ...
                '2. Check if image has extraneous areas \n' ...
                '    2c. If there are extraneous areas, \n         check Manual Top box and\n         draw polygon around membrane face \n'...
                '3. Manipulate the Threshold Slider until\n    Binary Mask Image is clear \n' ...
                '4. Manipulate Disc Size for further clarity \n' ...
                '5. Select mask to save, if any, on the right \n' ...
                '6. Click Analyze \n' ...
                '--- Detailed instructions for button functions are \n availible by hovering over each button---']),...
                'Instructions for DM & CM Calculator');
        end

        % Button pushed function: ImageAnalyzerButton
        function ImageAnalyzerButtonPushed(app, event)
            orig = imread(app.g_fileName);
            imD = fSEMDatabarCut(app, im2uint8(orig));
            imW = fAddSideBar(app, imD);

            if  strcmp(string(app.TopType.SelectedObject.Text), "No Top")
                app.g_imT = imW;
            end
            s = size(app.g_editedMask);
            st = size(s);
            if strcmp(string(app.MaskPicker_2.Value), "FMTM")
                %Manual Mask
                imageRegionAnalyzer(~app.g_imgFMTM);
            else
                %Auto Mask
                imageRegionAnalyzer(~app.g_imgFATM);
            end
        end

        % Button pushed function: EDVal
        function EDValButtonPushed(app, event)
            clipboard('copy',app.EDVal.Text);
        end

        % Button pushed function: BMVal
        function BMValButtonPushed(app, event)
            clipboard('copy',app.BMVal.Text);
        end

        % Button pushed function: EDCount
        function EDCountButtonPushed(app, event)
            clipboard('copy',app.EDCount.Text);
        end

        % Button pushed function: BMCount
        function BMCountButtonPushed(app, event)
            clipboard('copy',app.BMCount.Text);
        end

        % Button pushed function: ShrinkVal
        function ShrinkValButtonPushed(app, event)
            clipboard('copy',app.ShrinkVal.Text);
        end

        % Button pushed function: CopyDataButton
        function CopyDataButtonPushed(app, event)
            if strcmp(string(app.MaskPicker_2.Value), "FMTM")
                data = append(app.BMVal.Text, char(9), app.BMCount.Text, char(9), app.ShrinkVal.Text, char(13));
            else
                data = append(app.EDVal.Text, char(9), app.EDCount.Text, char(9), app.ShrinkVal.Text, char(13));
            end
            clipboard('copy', data);
        end

        % Button pushed function: CopyPoreDistButton
        function CopyPoreDistButtonPushed(app, event)
            if strcmp(string(app.MaskPicker_2.Value), "FMTM")
                data = table2array(app.g_ManPoreDist);
            else
                data = table2array(app.g_AutoPoreDist);
            end
            data = fDeleteDupl(app, data).*app.g_pSize_UM2;
            data = mat2str(data);
            data = strrep(data, ';', char(13));
            data = erase(data, ']');
            data = erase(data, '[');
            clipboard('copy', data);
        end

        % Value changed function: MaskPicker
        function MaskPickerValueChanged(app, event)
            %             app.MaskPicker_2.Value = app.MaskPicker.Value;
        end

        % Value changed function: MaskPicker_2
        function MaskPicker_2ValueChanged(app, event)
            if strcmp(string(app.MaskPicker_2.Value), "FMTM")
                app.CopyDataButton.FontColor = [0 1 0];
                app.CopyDataButton.BackgroundColor = [0.4941 0.1843 0.5569];
                app.CopyDataButton.Text = "Copy FMTM Data";

                app.CopyPoreDistButton.FontColor = [0 1 0];
                app.CopyPoreDistButton.BackgroundColor = [0.4941 0.1843 0.5569];
                app.CopyPoreDistButton.Text = "Copy FMTM Pore Dist";
            else
                app.CopyDataButton.FontColor = [0.0588 1 1];
                app.CopyDataButton.BackgroundColor = [0.6353 0.0784 0.1843];
                app.CopyDataButton.Text = "Copy FATM Data";

                app.CopyPoreDistButton.FontColor = [0.0588 1 1];
                app.CopyPoreDistButton.BackgroundColor = [0.6353 0.0784 0.1843];
                app.CopyPoreDistButton.Text = "Copy FATM Pore Dist";
            end
        end

        % Value changed function: NSize
        function NSizeValueChanged(app, event)
            app.NSize_Label.Value = 2*floor(app.NSize.Value/2)+1;
        end

        % Value changed function: NSize_Label
        function NSize_LabelValueChanged(app, event)
            app.NSize.Value = 2*floor(app.NSize_Label.Value/2)+1;
            app.NSize_Label.Value = app.NSize.Value;
        end

        % Button pushed function: ShrinkButton
        function ShrinkButtonPushed(app, event)
            app.ShrinkButton.Visible = 'off';
            app.ShrinkThickVal.Visible = 'on';
        end

        % Value changed function: ShrinkThickVal
        function ShrinkThickValValueChanged(app, event)
            if app.g_pLength_UM ~= -1
                [out, ~] = bwferet(app.g_bwROI, "MinFeretProperties");
                dUM = out.MinDiameter*app.g_pLength_UM;
                thic = app.ShrinkThickVal.Value;
                app.ShrinkVal.Text = num2str((thic-dUM)/thic);
            else
                app.ShrinkVal.Text = "no resolution data";
            end
            app.ShrinkButton.Visible = 'on';
            app.ShrinkThickVal.Visible = 'off';

        end

        % Button pushed function: ManResButton
        function ManResButtonPushed(app, event)
            app.ManResButton.Visible = 'off';
            app.ManResVal.Visible = 'on';
            app.AnalyzeButton.Enable = 'off';
            figure('Name', "Manual Resolution");
            imshow(imread(app.g_fileName));
            ax = gca;
            ROI = drawline(ax, 'Color', [0.2235 1 0.0784]);
            pos = ROI.Position;
            app.g_manDist = pdist(pos, 'euclidean');
            pause(0.1);
            close all;

            metadata = imread(app.g_fileName);
            s = size(metadata);
            metadata = metadata(floor(0.9*s(1)):floor(0.97*s(1)), floor(0.7*s(2)):floor(0.9*s(2)));
            txt = ocr(metadata);
            manResVal = abs(sscanf(txt.Text,'%d'));
            %             disp(manResVal);

            app.g_pLength_UM = (manResVal/app.g_manDist);
            app.g_pSize_UM2 = app.g_pLength_UM^2;
            if app.g_pLength_UM ~= -1
                [out, ~] = bwferet(app.g_bwROI, "MinFeretProperties");
                dUM = out.MinDiameter*app.g_pLength_UM;
                thic = app.ShrinkThickVal.Value;
                app.ShrinkVal.Text = num2str((thic-dUM)/thic);
                app.ManResButton.Visible = 'on';
                app.ManResVal.Visible = 'off';
                app.AnalyzeButton.Enable = 'on';
            else
                app.ShrinkVal.Text = "no resolution data";
            end
        end

        % Value changed function: ManResVal
        function ManResValValueChanged(app, event)
            app.g_pLength_UM = (app.ManResVal.Value/app.g_manDist);
            app.g_pSize_UM2 = app.g_pLength_UM^2;
            if app.g_pLength_UM ~= -1
                [out, ~] = bwferet(app.g_bwROI, "MinFeretProperties");
                dUM = out.MinDiameter*app.g_pLength_UM;
                thic = app.ShrinkThickVal.Value;
                app.ShrinkVal.Text = num2str((thic-dUM)/thic);
            else
                app.ShrinkVal.Text = "no resolution data";
            end
            app.ManResButton.Visible = 'on';
            app.ManResVal.Visible = 'off';
            app.AnalyzeButton.Enable = 'on';
        end

        % Button pushed function: OpenSkeletonButton
        function OpenSkeletonButtonPushed(app, event)
            app.OpenSkeletonButton.Visible = 0;
            app.OpenPainterButton.Visible = 0;
            app.LeftPanel.Enable = 'off';
            app.RightPanel.Enable = 'off';
            app.FilesList.Enable = 'off';
            app.MaskPicker.Enable = 'off';
            app.SkeletonizingPanel.Enable = 'on';
            app.SkeletonizingPanel.Visible = 1;
            clear img;
            img{1} = bwskel(app.g_imgFATM);
            img{2} = bwskel(app.g_imgFMTM);

            app.g_imgSkel = img;
        end

        % Button pushed function: SkelDoneButton
        function SkelDoneButtonPushed(app, event)
            close all;
            app.OpenSkeletonButton.Visible = 1;
            app.OpenPainterButton.Visible = 1;
            app.LeftPanel.Enable = 'on';
            app.RightPanel.Enable = 'on';
            app.MaskPicker.Enable = 'on';
            app.SkeletonizingPanel.Enable = 'off';
            app.FilesList.Enable = 'on';
            app.SkeletonizingPanel.Visible = 0;
        end

        % Value changed function: SkelThick
        function SkelThickValueChanged(app, event)
            app.SkelThickLabel.Value = floor(app.SkelThick.Value);
        end

        % Value changed function: SkelThickLabel
        function SkelThickLabelValueChanged(app, event)
            app.SkelThick.Value = floor(app.SkelThickLabel.Value);
        end

        % Button pushed function: ShowSkeletonButton
        function ShowSkeletonButtonPushed(app, event)
            img = app.g_imgSkel;
            %BETWEEN HERE
            %             global Areas;
            CC_FATM = bwconncomp(img{1}, 4);
            CC_FMTM = bwconncomp(img{2}, 4);
            %             RP_FMTM = regionprops("table", CC_FMTM, "Area")
            %             allAreas = table2array(RP_FMTM);
            % %             allAreas = allAreas(allAreas > 5);
            %             Areas = allAreas*app.g_pSize_UM2;
            % AND HERE is testing for the 4-conn pore distribution (so it
            % works)

            %BETWEEN HERE
            n = floor(app.SkelThick.Value);
            %                         se = strel('disc', n);
            %                         img{3} = imclose(img{1}, se);
            %                         img{4} = imclose(img{2}, se);
            %             img{3} = bwmorph(img{1}, "thicken", n);
            %             img{4} = bwmorph(img{2}, "thicken", n);
            %             img{3} = bwmorph(img{3}, "thin", n);
            %             img{4} = bwmorph(img{4}, "thin", n);
            img{3} = app.g_imT;
            img{4} = app.g_imgFMTM;

            app.g_imgSkel = img;
            %AND HERE is a mess
            %             FATMporeDist = sortrows(regionprops('table', ...
            %                 CC_FATM, "Area", "Centroid"), 'Area', 'descend');
            %             FMTMporeDist = sortrows(regionprops('table', ...
            %                 CC_FMTM, "Area", "Centroid"), 'Area', 'descend');
            %             disp(FMTMporeDist(:, 2:end));

            fig = figure();
            titles = ["FATM skeleton", "FMTM skeleton", "grayscale", "FMTM "];
            tlo = tiledlayout(fig, 2, 2, 'TileSpacing', 'compact');
            for i = 1:numel(img)
                ax = nexttile(tlo);
                hold on;
                imshow(img{i}, 'Parent', ax);
                CC = bwconncomp(~img{2}, 4);
                poreDist = regionprops('table', ...
                    CC, "Centroid");
                poreDist = table2array(poreDist);
                plot(poreDist(:, 1), poreDist(:,2),...
                    "Marker",".", "MarkerSize", 10, ...
                    "LineStyle","none", "Color", "yellow");
                hold off;
                title([titles(i)]);
            end
        end

        % Value changed function: MaskPicker_3
        function MaskPicker_3ValueChanged(app, event)
            if app.MaskPicker_3.Value == 1
                app.MaskPicker_3.FontColor = [0 1 0];
                app.MaskPicker_3.BackgroundColor = [0.4941 0.1843 0.5569];
                app.CopySkelPoreDistButton.FontColor = [0 1 0];
                app.CopySkelPoreDistButton.BackgroundColor = [0.4941 0.1843 0.5569];
                app.MaskPicker_3.Text = "FMTM";
            else
                app.MaskPicker_3.FontColor = [0.0588 1 1];
                app.MaskPicker_3.BackgroundColor = [0.6353 0.0784 0.1843];
                app.CopySkelPoreDistButton.FontColor = [0.0588 1 1];
                app.CopySkelPoreDistButton.BackgroundColor = [0.6353 0.0784 0.1843];
                app.MaskPicker_3.Text = "FATM";
            end
        end

        % Button pushed function: CopySkelPoreDistButton
        function CopySkelPoreDistButtonPushed(app, event)
            img = app.g_imgSkel;
            CC_FATM = bwconncomp(~img{1}, 4);
            CC_FMTM = bwconncomp(~img{2}, 4);
            FATMporeDist = sortrows(regionprops('table', ...
                CC_FATM, "Area", "Centroid"), 'Area', 'descend');
            FMTMporeDist = sortrows(regionprops('table', ...
                CC_FMTM, "Area", "Centroid"), 'Area', 'descend');
            disp(FMTMporeDist(:, 2:end));
            if strcmp(string(app.MaskPicker_3.Text), "FMTM")
                disp("Copied FMTM data");
                data = table2array(FMTMporeDist);
            else
                disp("Copied FATM data");
                data = table2array(FATMporeDist);
            end
            data = data(:, 1)*app.g_pSize_UM2;
            data = mat2str(data);
            data = strrep(data, ';', char(13));
            data = erase(data, ']');
            data = erase(data, '[');
            clipboard('copy', data);
        end

        % Value changed function: OverlayButton_2
        function OverlayButton_2ValueChanged(app, event)
%             editedMask = app.g_editedMask;
%             s = size(editedMask);
%             st = size(s);
%             if st(2) < 3
%                 previousMask = editedMask(:,:,1);
%             else
%                 previousMask = editedMask(:,:,end);
%             end
%             if app.OverlayButton_2.Value == 1
%                 seePMask = imdilate(bwperim(previousMask), strel('disk', 3));
%                 colormap(app.FMTM, "gray");
%                 imagesc(app.FMTM, im2uint16(imoverlay(app.g_imT, seePMask)));
%             else
%                 colormap(app.FMTM, "gray");
%                 imagesc(app.FMTM, im2uint16(previousMask));
%             end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {735, 735, 735};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {735, 735};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {215, '1x', 190};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ThresholdFMTMLabel = uilabel(app.LeftPanel);
            app.ThresholdFMTMLabel.Tooltip = {'Decimal values from 0-1'};
            app.ThresholdFMTMLabel.Text = 'Threshold (FMTM)';
            app.T = uislider(app.LeftPanel);
            app.T.Limits = [0 255];
            app.T.ValueChangedFcn = createCallbackFcn(app, @TValueChanged, true);
            app.T.Tooltip = {'Decimal values from 0-255'};
            app.T.Value = 127.5;
            app.AnalyzeButton = uibutton(app.LeftPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Tooltip = {'When pressed, DM and CM will be calculated for FATM  and FMTM. Depending on what is selected under the "Save Images" panel, image(s) may be saved to the folder the original image is from.'};
            app.AnalyzeButton.Text = 'Analyze';
            app.FileNameGUI = uibutton(app.LeftPanel, 'push');
            app.FileNameGUI.ButtonPushedFcn = createCallbackFcn(app, @FileNameGUIPushed, true);
            app.FileNameGUI.Tooltip = {'Select image with GUI (.tif or .tiff files)'};
            app.FileNameGUI.Text = 'Load Image';
            app.RunTime = uilabel(app.LeftPanel);
            app.RunTime.Tooltip = {'Run time of code'};
            app.RunTime.Text = 'Run Time';
            app.Lamp = uilamp(app.LeftPanel);
            app.Lamp.Tooltip = {'If lamp is red, program is running, please wait for lamp to turn off.'};
            app.Label = uilabel(app.LeftPanel);
            app.Label.Tooltip = {''};
            app.Label.Text = '___';
            app.DrawPerimeterSwitchLabel = uilabel(app.LeftPanel);
            app.DrawPerimeterSwitchLabel.Visible = 'off';
            app.DrawPerimeterSwitchLabel.Text = 'Draw Perimeter';
            app.DrawPerimeterSwitch = uiswitch(app.LeftPanel, 'slider');
            app.DrawPerimeterSwitch.Visible = 'off';
            app.DrawPerimeterSwitch.Tooltip = {'Draws the perimeter around the membrane to help with processing'};
            app.DrawPerimeterSwitch.Value = 'On';
            app.StrelSize = uispinner(app.LeftPanel);
            app.StrelSize.Limits = [0 30];
            app.StrelSize.ValueChangedFcn = createCallbackFcn(app, @StrelSizeValueChanged, true);
            app.StrelSize.Tooltip = {'Integer Values from 0-20'};
            app.StrelSize.Value = 6;
            app.DiscSizeLabel = uilabel(app.LeftPanel);
            app.DiscSizeLabel.Text = 'Disc Size';
            app.ImagesSavedLabel = uilabel(app.LeftPanel);
            app.ImagesSavedLabel.Visible = 'off';
            app.ImagesSavedLabel.Text = 'Images Saved';
            app.TopType = uibuttongroup(app.LeftPanel);
            app.TopType.SelectionChangedFcn = createCallbackFcn(app, @TopTypeSelectionChanged, true);
            app.ManualTopButton = uiradiobutton(app.TopType);
            app.ManualTopButton.Tooltip = {'Only crops out SEM Metadata bar'};
            app.ManualTopButton.Text = 'Manual Top';
            app.NoTopButton = uiradiobutton(app.TopType);
            app.NoTopButton.Tooltip = {'Enables external GUI to draw polygon around membrane face'};
            app.NoTopButton.Text = 'No Top';
            app.NoTopButton.Value = true;
            app.FilterMethodButtonGroup = uibuttongroup(app.LeftPanel);
            app.FilterMethodButtonGroup.Tooltip = {'Enables different filtering methods to remove micropores'};
            app.FilterMethodButtonGroup.Title = 'Filter Method';
            app.PercentageButton = uitogglebutton(app.FilterMethodButtonGroup);
            app.PercentageButton.Text = 'Percentage';
            app.SizeButton = uitogglebutton(app.FilterMethodButtonGroup);
            app.SizeButton.Text = 'Size';
            app.NoneButton_2 = uitogglebutton(app.FilterMethodButtonGroup);
            app.NoneButton_2.Text = 'None';
            app.NoneButton_2.Value = true;
            app.NeighborhoodSizeFATMLabel = uilabel(app.LeftPanel);
            app.NeighborhoodSizeFATMLabel.Tooltip = {'Decimal values from 0-1'};
            app.NeighborhoodSizeFATMLabel.Text = 'Neighborhood Size (FATM)';
            app.NSize = uislider(app.LeftPanel);
            app.NSize.Limits = [11 1001];
            app.NSize.ValueChangedFcn = createCallbackFcn(app, @NSizeValueChanged, true);
            app.NSize.Tooltip = {'Manipulates the neighborhood size for FATM. '; 'Integer values from 61-1000'};
            app.NSize.Value = 61;
            app.ConnectedButton = uibutton(app.LeftPanel, 'state');
            app.ConnectedButton.Tooltip = {'Toggle this if the polymer face is unconnected'};
            app.ConnectedButton.Text = 'Connected?';
            app.ConnectedButton.Value = true;
            app.ManResButton = uibutton(app.LeftPanel, 'push');
            app.ManResButton.ButtonPushedFcn = createCallbackFcn(app, @ManResButtonPushed, true);
            app.ManResButton.Visible = 'off';
            app.ManResButton.Tooltip = {'Enables tracing of the scalebar and scale to allow for manual entry of the image resolution.'};
            app.ManResButton.Text = 'Manual Resolution';
            app.ManResVal = uieditfield(app.LeftPanel, 'numeric');
            app.ManResVal.Limits = [0 Inf];
            app.ManResVal.ValueDisplayFormat = '%11g \xB5M';
            app.ManResVal.ValueChangedFcn = createCallbackFcn(app, @ManResValValueChanged, true);
            app.ManResVal.Visible = 'off';
            app.ManResVal.Value = 5;
            app.NSize_Label = uieditfield(app.LeftPanel, 'numeric');
            app.NSize_Label.Limits = [11 1001];
            app.NSize_Label.RoundFractionalValues = 'on';
            app.NSize_Label.ValueDisplayFormat = '%11g';
            app.NSize_Label.ValueChangedFcn = createCallbackFcn(app, @NSize_LabelValueChanged, true);
            app.NSize_Label.Tooltip = {'Enter neighorhood size for automated thresholding, default is 61. Accepts any integer but will convert it to the nearest odd number.'};
            app.NSize_Label.Value = 61;
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.Tooltip = {'Select between FATM and FMTM for both "Painter" and "Image Analyzer" button.'};
            app.FATM = uiaxes(app.CenterPanel);
            title(app.FATM, 'Filled Adaptive Thresholding Mask (FATM)')
            app.FATM.XTick = [];
            app.FATM.XTickLabel = {'[ ]'};
            app.FATM.YTick = [];
            app.FMTM = uiaxes(app.CenterPanel);
            title(app.FMTM, 'Filled Manual Thresholding Mask (FMTM)')
            app.FMTM.XAxisLocation = 'top';
            app.FMTM.XTick = [];
            app.FMTM.XTickLabel = {'[ ]'};
            app.FMTM.YTick = [];
            app.SkeletonizingPanel = uipanel(app.CenterPanel);
            app.SkeletonizingPanel.Title = 'Skeletonizing Panel';
            app.SkeletonizingPanel.Visible = 'off';
            app.CopySkelPoreDistButton = uibutton(app.SkeletonizingPanel, 'push');
            app.CopySkelPoreDistButton.ButtonPushedFcn = createCallbackFcn(app, @CopySkelPoreDistButtonPushed, true);
            app.CopySkelPoreDistButton.Tooltip = {'Copies the pore distribution of the skeletonized mask shown on the button on the left.'};
            app.CopySkelPoreDistButton.Text = 'Copy Pore Dist';
            app.ShowSkeletonButton = uibutton(app.SkeletonizingPanel, 'push');
            app.ShowSkeletonButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSkeletonButtonPushed, true);
            app.ShowSkeletonButton.Tooltip = {'Shows FATM/FMTM Skeletonized, grayscale, and FMTM with yellow dots ontop of where it detects pores.'};
            app.ShowSkeletonButton.Text = 'Show Skeleton';
            app.SkelThickLabel = uieditfield(app.SkeletonizingPanel, 'numeric');
            app.SkelThickLabel.Limits = [0 100];
            app.SkelThickLabel.RoundFractionalValues = 'on';
            app.SkelThickLabel.ValueDisplayFormat = '%11g';
            app.SkelThickLabel.ValueChangedFcn = createCallbackFcn(app, @SkelThickLabelValueChanged, true);
            app.SkelThickLabel.Tooltip = {'Enter the thickening amount for the skeletons between 0 and 10'};
            app.ThickeningSizeLabel = uilabel(app.SkeletonizingPanel);
            app.ThickeningSizeLabel.Tooltip = {'Decimal values from 0-1'};
            app.ThickeningSizeLabel.Text = 'Thickening Size';
            app.SkelThick = uislider(app.SkeletonizingPanel);
            app.SkelThick.Limits = [0 10];
            app.SkelThick.ValueChangedFcn = createCallbackFcn(app, @SkelThickValueChanged, true);
            app.SkelThick.Tooltip = {'Manipulates the thickening amount for the skeletons. Ranges between 0 and 10.'};
            app.SkelDoneButton = uibutton(app.SkeletonizingPanel, 'push');
            app.SkelDoneButton.ButtonPushedFcn = createCallbackFcn(app, @SkelDoneButtonPushed, true);
            app.SkelDoneButton.Tooltip = {'Closes this panel'};
            app.SkelDoneButton.Text = 'Done';
            app.MaskPicker_3 = uibutton(app.SkeletonizingPanel, 'state');
            app.MaskPicker_3.ValueChangedFcn = createCallbackFcn(app, @MaskPicker_3ValueChanged, true);
            app.MaskPicker_3.Tooltip = {'Switch between FMTM and FATM for copying the pore distribution.'};
            app.MaskPicker_3.Text = 'FMTM';
            app.CurrentFileNameLabel = uilabel(app.CenterPanel);
            app.CurrentFileNameLabel.Visible = 'off';
            app.CurrentFileNameLabel.Text = 'Current File Name';
            app.TitleLabel = uilabel(app.CenterPanel);
            app.TitleLabel.Text = 'DM & CM Calculator v1.21e (Apreo, FEI, Zeiss SEMs)';
            app.ImageCollection = uitabgroup(app.CenterPanel);
            app.OriginalSEMImageTab = uitab(app.ImageCollection);
            app.OriginalSEMImageTab.Title = 'Original SEM Image';
            app.OrigImage = uiaxes(app.OriginalSEMImageTab);
            app.OrigImage.XTick = [];
            app.OrigImage.XTickLabel = {'[ ]'};
            app.OrigImage.YTick = [];
            app.FrontFaceOnlyTab = uitab(app.ImageCollection);
            app.FrontFaceOnlyTab.Title = 'Front Face Only';
            app.DebugImages = uiaxes(app.FrontFaceOnlyTab);
            app.DebugImages.XTick = [];
            app.DebugImages.XTickLabel = {'[ ]'};
            app.DebugImages.YTick = [];
            app.CurrentEditedMaskTab = uitab(app.ImageCollection);
            app.CurrentEditedMaskTab.Title = 'Current Edited Mask';
            app.PaintedImage = uiaxes(app.CurrentEditedMaskTab);
            app.PaintedImage.XTick = [];
            app.PaintedImage.XTickLabel = {'[ ]'};
            app.PaintedImage.YTick = [];
            app.ImagesinImmediateFolderLabel = uilabel(app.CenterPanel);
            app.ImagesinImmediateFolderLabel.Text = 'Images in Immediate Folder';
            app.FilesList = uilistbox(app.CenterPanel);
            app.FilesList.Items = {};
            app.FilesList.ValueChangedFcn = createCallbackFcn(app, @FilesListValueChanged, true);
            app.FilesList.Tooltip = {'All other images in the folder of the original selected image will appear here. Click on another file name to select that image'};
            app.FilesList.Value = {};
            app.OpenPainterButton = uibutton(app.CenterPanel, 'push');
            app.OpenPainterButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPainterButtonPushed, true);
            app.OpenPainterButton.Tooltip = {'Opens "Painting Tools" menu.'};
            app.OpenPainterButton.Text = 'Open Painter';
            app.PainterPanel = uipanel(app.CenterPanel);
            app.PainterPanel.Title = 'Painting Tools';
            app.PainterPanel.Visible = 'off';
            app.DoneButton = uibutton(app.PainterPanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Tooltip = {'Saves edit and calculates new DM and CMs'};
            app.DoneButton.Text = 'Done';
            app.NewEditButton = uibutton(app.PainterPanel, 'push');
            app.NewEditButton.ButtonPushedFcn = createCallbackFcn(app, @NewEditButtonPushed, true);
            app.NewEditButton.Tooltip = {'Opens additional figure to add edits. Edits are added as polygons are completed.'};
            app.NewEditButton.Text = 'New Edit';
            app.UndoButton = uibutton(app.PainterPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Tooltip = {'Reverts to previous edit'};
            app.UndoButton.Text = 'Undo';
            app.FillingColorSwitchLabel = uilabel(app.PainterPanel);
            app.FillingColorSwitchLabel.Text = 'Filling Color';
            app.FillingColorSwitch = uiswitch(app.PainterPanel, 'slider');
            app.FillingColorSwitch.Items = {'White', 'Black'};
            app.FillingColorSwitch.Tooltip = {'Allows for selection between ''adding'' white or ''removing'' white. '};
            app.FillingColorSwitch.Value = 'White';
            app.OverlayButton = uibutton(app.PainterPanel, 'state');
            app.OverlayButton.ValueChangedFcn = createCallbackFcn(app, @OverlayButtonValueChanged, true);
            app.OverlayButton.Tooltip = {'Enables/disables the yellow outline overlay for the painter'};
            app.OverlayButton.Text = 'Overlay';
            app.MaskPicker = uiswitch(app.CenterPanel, 'slider');
            app.MaskPicker.Items = {'FATM', 'FMTM'};
            app.MaskPicker.ValueChangedFcn = createCallbackFcn(app, @MaskPickerValueChanged, true);
            app.MaskPicker.Value = 'FATM';
            app.ClearEditsButton = uibutton(app.CenterPanel, 'push');
            app.ClearEditsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearEditsButtonPushed, true);
            app.ClearEditsButton.Tooltip = {'Removes all edits.'};
            app.ClearEditsButton.Text = 'Clear Edits';
            app.OverlayButton_2 = uibutton(app.CenterPanel, 'state');
            app.OverlayButton_2.ValueChangedFcn = createCallbackFcn(app, @OverlayButton_2ValueChanged, true);
            app.OverlayButton_2.Enable = 'off';
            app.OverlayButton_2.Visible = 'off';
            app.OverlayButton_2.Text = 'Overlay';
            app.RightPanel = uipanel(app.GridLayout);
            app.ShrinkButton = uibutton(app.RightPanel, 'push');
            app.ShrinkButton.ButtonPushedFcn = createCallbackFcn(app, @ShrinkButtonPushed, true);
            app.ShrinkButton.Tooltip = {'Clicking on "Shrinkage" reveals an option to change the nominal thickness of the membrane. Defaults to 250 microns. '};
            app.ShrinkButton.Text = 'Shrinkage';
            app.BMVal = uibutton(app.RightPanel, 'push');
            app.BMVal.ButtonPushedFcn = createCallbackFcn(app, @BMValButtonPushed, true);
            app.BMVal.Tooltip = {'Clicking on the number copies it to the clipboard'};
            app.BMVal.Text = '___';
            app.EDVal = uibutton(app.RightPanel, 'push');
            app.EDVal.ButtonPushedFcn = createCallbackFcn(app, @EDValButtonPushed, true);
            app.EDVal.Tooltip = {'Clicking on the number copies it to the clipboard'};
            app.EDVal.Text = '___';
            app.BinaryMaskLabel = uilabel(app.RightPanel);
            app.BinaryMaskLabel.Tooltip = {'Macroporosity from Filled Automated Thresholding Mask.'};
            app.BinaryMaskLabel.Text = 'FMTM DM';
            app.EdgeDetectLabel = uilabel(app.RightPanel);
            app.EdgeDetectLabel.Tooltip = {'Macroporosity from Filled Automated Thresholding Mask.'};
            app.EdgeDetectLabel.Text = 'FATM DM';
            app.EDCount = uibutton(app.RightPanel, 'push');
            app.EDCount.ButtonPushedFcn = createCallbackFcn(app, @EDCountButtonPushed, true);
            app.EDCount.Tooltip = {'Clicking on the number copies it to the clipboard'};
            app.EDCount.Text = '___';
            app.EdgeDetectLabel_2 = uilabel(app.RightPanel);
            app.EdgeDetectLabel_2.Tooltip = {'Macrovoid count density (voids/um^2) from Filled Automated Thresholding Mask. '};
            app.EdgeDetectLabel_2.Text = 'FATM CM';
            app.BMCount = uibutton(app.RightPanel, 'push');
            app.BMCount.ButtonPushedFcn = createCallbackFcn(app, @BMCountButtonPushed, true);
            app.BMCount.Tooltip = {'Clicking on the number copies it to the clipboard'};
            app.BMCount.Text = '___';
            app.BinaryMaskLabel_2 = uilabel(app.RightPanel);
            app.BinaryMaskLabel_2.Tooltip = {'Macrovoid count density (voids/um^2) from Filled Manual Thresholding Mask.'};
            app.BinaryMaskLabel_2.Text = 'FMTM CM';
            app.SaveImagesButtonGroup = uibuttongroup(app.RightPanel);
            app.SaveImagesButtonGroup.Tooltip = {'The image(s) selected will automatically be saved upon pressing "Analyze"'};
            app.SaveImagesButtonGroup.Title = 'Save Images';
            app.NoneButton = uitogglebutton(app.SaveImagesButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.FATMButton = uitogglebutton(app.SaveImagesButtonGroup);
            app.FATMButton.Text = 'FATM';
            app.FMTMButton = uitogglebutton(app.SaveImagesButtonGroup);
            app.FMTMButton.Text = 'FMTM';
            app.BothButton = uitogglebutton(app.SaveImagesButtonGroup);
            app.BothButton.Text = 'Both';
            app.InstructionsButton = uibutton(app.RightPanel, 'push');
            app.InstructionsButton.ButtonPushedFcn = createCallbackFcn(app, @InstructionsButtonPushed, true);
            app.InstructionsButton.Tooltip = {'Press for instructions'};
            app.InstructionsButton.Text = 'Instructions';
            app.ImageAnalyzerButton = uibutton(app.RightPanel, 'push');
            app.ImageAnalyzerButton.ButtonPushedFcn = createCallbackFcn(app, @ImageAnalyzerButtonPushed, true);
            app.ImageAnalyzerButton.Tooltip = {'Press for external GUI for more detailed morphological details'};
            app.ImageAnalyzerButton.Text = 'Image Analyzer';
            app.ShrinkVal = uibutton(app.RightPanel, 'push');
            app.ShrinkVal.ButtonPushedFcn = createCallbackFcn(app, @ShrinkValButtonPushed, true);
            app.ShrinkVal.Tooltip = {'Clicking on the number copies it to the clipboard'};
            app.ShrinkVal.Text = '___';
            app.CopyDataButton = uibutton(app.RightPanel, 'push');
            app.CopyDataButton.ButtonPushedFcn = createCallbackFcn(app, @CopyDataButtonPushed, true);
            app.CopyDataButton.Tooltip = {'Clicking here copies the DM, CM, and Shrinkage % values for FMTM to clipboard, for pasting in that order in Excel.'};
            app.CopyDataButton.Text = 'Copy FATM Data';
            app.ShrinkThickVal = uieditfield(app.RightPanel, 'numeric');
            app.ShrinkThickVal.Limits = [0 Inf];
            app.ShrinkThickVal.ValueDisplayFormat = '%11g \xB5M';
            app.ShrinkThickVal.ValueChangedFcn = createCallbackFcn(app, @ShrinkThickValValueChanged, true);
            app.ShrinkThickVal.Visible = 'off';
            app.ShrinkThickVal.Value = 250;
            app.CopyPoreDistButton = uibutton(app.RightPanel, 'push');
            app.CopyPoreDistButton.ButtonPushedFcn = createCallbackFcn(app, @CopyPoreDistButtonPushed, true);
            app.CopyPoreDistButton.Tooltip = {'Copies the pore distribution of the mask selected in micrometers^2'};
            app.CopyPoreDistButton.Text = 'Copy FATM Pore Dist';
            app.MaskPicker_2 = uiswitch(app.RightPanel, 'slider');
            app.MaskPicker_2.Items = {'FATM', 'FMTM'};
            app.MaskPicker_2.ValueChangedFcn = createCallbackFcn(app, @MaskPicker_2ValueChanged, true);
            app.MaskPicker_2.Tooltip = {'Selects FATM/FMTM for the copy data buttons and Image Analyzer buttons below'};
            app.MaskPicker_2.Value = 'FATM';
            app.OpenSkeletonButton = uibutton(app.RightPanel, 'push');
            app.OpenSkeletonButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSkeletonButtonPushed, true);
            app.OpenSkeletonButton.Visible = 'off';
            app.OpenSkeletonButton.Tooltip = {'Opens "Painting Tools" menu.'};
            app.OpenSkeletonButton.Text = 'Open Skeleton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DM_CM_Calculator_Experimental_APREOFEI_Quanta_SourceCode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end